classdef Analysisgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        BrowseButton              matlab.ui.control.Button
        DataFolderEditFieldLabel  matlab.ui.control.Label
        DataFolderEditField       matlab.ui.control.EditField
        AnalysisDropDownLabel     matlab.ui.control.Label
        AnalysisDropDown          matlab.ui.control.DropDown
        ElectrodesEditFieldLabel  matlab.ui.control.Label
        ElectrodesEditField       matlab.ui.control.EditField
        TrialIDEditFieldLabel     matlab.ui.control.Label
        TrialIDEditField          matlab.ui.control.NumericEditField
        GoButton                  matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            DIR = uigetdir;
            app.DataFolderEditField.Value = DIR;
            cd(app.DataFolderEditField.Value)
        end

        % Value changed function: DataFolderEditField
        function DataFolderEditFieldValueChanged(app, event)
            value = app.DataFolderEditField.Value;
            
        end

        % Value changed function: AnalysisDropDown
        function AnalysisDropDownValueChanged(app, event)
            value = app.AnalysisDropDown.Value;
            if strcmp(value,'PSTH')
                app.ElectrodesEditFieldLabel.Visible='On';
                app.ElectrodesEditField.Visible='On';
                app.TrialIDEditFieldLabel.Visible='On';
                app.TrialIDEditField.Visible='On';
            elseif strcmp(value,'VisualStimPSTH')
                app.ElectrodesEditFieldLabel.Visible='On';
                app.ElectrodesEditField.Visible='On';
                app.TrialIDEditFieldLabel.Visible='Off';
                app.TrialIDEditField.Visible='Off';
            elseif strcmp(value,'Current Sigmoid')
                app.ElectrodesEditFieldLabel.Visible='On';
                app.ElectrodesEditField.Visible='On';
                app.TrialIDEditFieldLabel.Visible='Off';
                app.TrialIDEditField.Visible='Off';
            elseif strcmp(value,'Channel Heatmap')
                app.ElectrodesEditFieldLabel.Visible='Off';
                app.ElectrodesEditField.Visible='Off';
                app.TrialIDEditFieldLabel.Visible='On';
                app.TrialIDEditField.Visible='On';
            elseif strcmp(value,'VisualStimHeatmap')
                app.ElectrodesEditFieldLabel.Visible='Off';
                app.ElectrodesEditField.Visible='Off';
                app.TrialIDEditFieldLabel.Visible='Off';
                app.TrialIDEditField.Visible='Off';
            end
        end

        % Value changed function: ElectrodesEditField
        function ElectrodesEditFieldValueChanged(app, event)
            value = app.ElectrodesEditField.Value;
            
        end

        % Value changed function: TrialIDEditField
        function TrialIDEditFieldValueChanged(app, event)
            value = app.TrialIDEditField.Value;
                        
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            
            if strcmp(app.AnalysisDropDown.Value,'PSTH')
                ID=app.TrialIDEditField.Value;
                amplifier_channels=read_Intan_RHS2000_file;
                nChn=length(amplifier_channels);
                Chns=app.ElectrodesEditField.Value;
                Spk_array=OnlineFRresponse(Chns,nChn,ID);
                assignin('base','Spk_array',Spk_array);
            elseif strcmp(app.AnalysisDropDown.Value,'VisualStimPSTH')
                    amplifier_channels=read_Intan_RHS2000_file;
                    nChn=length(amplifier_channels);
                    Chns=app.ElectrodesEditField.Value;
                    Spk_array=OnlineFlashFRresponse(Chns,nChn);
                    assignin('base','Spk_array',Spk_array);
            elseif strcmp(app.AnalysisDropDown.Value,'Current Sigmoid')
                electrodes=app.ElectrodesEditField.Value;% for all electrodes = Depth(1)
                if strcmp(electrodes,'all')
                    electrodes='';
                    temp = ProbeMAP;
                    for i=2:nChn+1
                        electrodes=strcat(electrodes,temp{i,6},',');
                    end
                    electrodes(end)=[];
                end
                amplifier_channels=read_Intan_RHS2000_file;
                nChn=length(amplifier_channels);
                Spk_array=OnlineSigmoidGenerator(electrodes,nChn);
                assignin('base','Spk_array',Spk_array);
            elseif strcmp(app.AnalysisDropDown.Value,'Channel Heatmap')
                ID=app.TrialIDEditField.Value;
                amplifier_channels=read_Intan_RHS2000_file;
                nChn=length(amplifier_channels);
                array=OnlineHeatmap(nChn,ID);
                assignin('base','array',array);
            elseif strcmp(app.AnalysisDropDown.Value,'VisualStimHeatmap')
                amplifier_channels=read_Intan_RHS2000_file;
                nChn=length(amplifier_channels);
                array=OnlineVisualStimHeatmap(nChn);
                assignin('base','array',array);
            end

        end

        % Callback function
        function AnalysisDropDownOpening(app, event)
            app.AnalysisDropDown.Value = 'PSTH';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.DataFolderEditFieldLabel = uilabel(app.UIFigure);
            app.DataFolderEditFieldLabel.Text = 'Data Folder';
            app.DataFolderEditField = uieditfield(app.UIFigure, 'text');
            app.DataFolderEditField.ValueChangedFcn = createCallbackFcn(app, @DataFolderEditFieldValueChanged, true);
            app.AnalysisDropDownLabel = uilabel(app.UIFigure);
            app.AnalysisDropDownLabel.Text = 'Analysis';
            app.AnalysisDropDown = uidropdown(app.UIFigure);
            app.AnalysisDropDown.Items = {'PSTH', 'VisualStimPSTH', 'Current Sigmoid', 'Channel Heatmap', 'VisualStimHeatmap'};
            app.AnalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisDropDownValueChanged, true);
            app.AnalysisDropDown.Value = 'PSTH';
            app.ElectrodesEditFieldLabel = uilabel(app.UIFigure);
            app.ElectrodesEditFieldLabel.Text = 'Electrodes';
            app.ElectrodesEditField = uieditfield(app.UIFigure, 'text');
            app.ElectrodesEditField.ValueChangedFcn = createCallbackFcn(app, @ElectrodesEditFieldValueChanged, true);
            app.ElectrodesEditField.Value = 'e.g. A-013,D-016 OR ''all''';
            app.TrialIDEditFieldLabel = uilabel(app.UIFigure);
            app.TrialIDEditFieldLabel.Text = 'Trial ID';
            app.TrialIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrialIDEditField.ValueChangedFcn = createCallbackFcn(app, @TrialIDEditFieldValueChanged, true);
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysisgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end